classdef project_03_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        pushbuttonAdjustCLAHE      matlab.ui.control.Button
        editContrastLevelHI        matlab.ui.control.EditField
        labelContrastLevelHI       matlab.ui.control.Label
        sliderContrastLevelHI      matlab.ui.control.Slider
        pushbuttonEquiliaze        matlab.ui.control.Button
        editContrastLevelLI        matlab.ui.control.EditField
        labelContrastLevelLI       matlab.ui.control.Label
        sliderContrastLevelLI      matlab.ui.control.Slider
        labelTreshold              matlab.ui.control.Label
        pushbuttonAdjustContrastL  matlab.ui.control.Button
        editTreshold               matlab.ui.control.EditField
        sliderTreshold             matlab.ui.control.Slider
        labelStatus                matlab.ui.control.Label
        pushbuttonRotateCC         matlab.ui.control.Button
        pushbuttonRotateC          matlab.ui.control.Button
        pushbuttonFlipV            matlab.ui.control.Button
        pushbuttonFlipH            matlab.ui.control.Button
        pushbuttonReset            matlab.ui.control.Button
        pushbuttonSegment          matlab.ui.control.Button
        pushbuttonLoad             matlab.ui.control.Button
        axes1                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img
        cmaps
        info
        imgTreshold
        contrastLevelL
        contrastLevelH
        eventSource
        lastFilePath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function project_GUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to project_GUI (see VARARGIN)
            
            % Choose default command line output for project_GUI
            handles.output = hObject;
           
            app.lastFilePath = "";
            app.eventSource = "";
            app.imgTreshold = 0.6;                    
            app.sliderTreshold.Value = app.imgTreshold;
            app.editTreshold.Value = num2str(app.imgTreshold);
            app.contrastLevelL = 0.2;
            app.sliderContrastLevelLI.Value = app.contrastLevelL;
            app.editContrastLevelLI.Value = num2str(app.contrastLevelL);
            app.contrastLevelH = 0.8;
            app.sliderContrastLevelHI.Value = app.contrastLevelH;
            app.editContrastLevelHI.Value = num2str(app.contrastLevelH);
            app.labelStatus.Visible = 0;

            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbuttonLoad
        function pushbuttonLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file,path] = uigetfile('*.dcm');
            
            file = strcat(path,'/',file);           
            app.info = dicominfo(file);
            if isfield(app.info,'RescaleSlope') == 1
               slope = app.info.RescaleSlope;
            else
               slope = 1;
            end
            if isfield(app.info,'RescaleIntercept') == 1
               intercept = app.info.RescaleIntercept;
            else
               intercept = 0;
            end
            cmap__1 = gray;
            new__cmap = [cmap__1];
            app.cmaps =  new__cmap;
            app.img = dicomread(file);
            app.img = double(app.img);
            app.img = app.img.*slope + intercept;
            app.lastFilePath = file;
            imshow(app.img); colormap(app.cmaps); caxis('auto'); colorbar
            app.labelStatus.Text = 'Image is loaded.';
            app.labelStatus.Visible = 1;

        end

        % Button pushed function: pushbuttonSegment
        function pushbuttonSegment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
 
            if isfield(app.info,'PixelSpacing') == 1
               dimx = app.info.PixelSpacing(1);
               dimy = app.info.PixelSpacing(2);
            else
               dimx = 0.05; % 50 micro meters, from PeerJ paper
               dimy = 0.05; % 50 micro meters, from PeerJ paper
            end
            if isfield(app.info,'SliceThickness') == 1
               dimz = app.info.SliceThickness;
            else
               dimz = 1;
            end

            % Calculate the dimension of a single voxel [mm^3]
            dim__voxel = dimx*dimy*dimz;          
            
            h = imfreehand;
            
            % Position of the ROI
            % x- and y-coordinates, respectively, of the n points along the boundary
            % of the freehand region
            pos = getPosition(h);
                
            % Creating a matrix that contains only the selected ROI
            mask = createMask(h);
            img__roi = app.img.*mask;
            
            % Calculate the threshold
            max__roi = max(max(img__roi));
            threshold = app.imgTreshold * max__roi;
            
            % Apply the threshold to the ROI
            img__roithreshold = img__roi;
            img__roithreshold(img__roithreshold < threshold) = 0;
            % imshow(img__roithreshold,[],'Colormap',hot); colorbar;
            
            % We have now two images to display: the background image and the
            % thresholded ROI.
            % In order to plot these two images as superimposed on the same figure,
            % we should create a single image with values on two different color
            % scales (in order to make them visually distinguishable).
            % This image will then be plotted using two different colormaps stacked one
            % over the other. Let us prepare a two-maps-in-one colormap by stacking a
            % jet colormap and a hot colormap.
            cmap__1 = colormap(gray);
            cmap__2 = colormap(jet);
            new__cmap = [cmap__1; cmap__2];
            
            % Let us prepare the new image to be plotted. This new image will have the
            % original-image values outside our thresholded ROI, and the new values
            % inside the thresholded ROI.
            % The new values will simply be the original values plus the maximum of the
            % original image.
            background__img = app.img./max(max(app.img));
            foreground__img = img__roithreshold./max(max(img__roithreshold));
            new__img = background__img;
            for i = 1:size(app.img,1)
                for j = 1:size(app.img,2)
                    if foreground__img(i,j) ~= 0
                        new__img(i,j) = 1.0001 + foreground__img(i,j);
                    end
                end
            end
            app.img = new__img;
            app.cmaps =  new__cmap;
            % Plot the resulting image using the new colormap
            imshow(app.img); colormap(app.cmaps); caxis('auto'); colorbar
            nvoxel = nnz(img__roithreshold > 0);
            app.labelStatus.Text = strcat("Calculated volume: ", num2str(nvoxel * dim__voxel));
            app.labelStatus.Visible = 1;
        end

        % Button pushed function: pushbuttonReset
        function pushbuttonReset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.lastFilePath, "") == true
                return
            end
            file = app.lastFilePath;
            app.info = dicominfo(file);
            if isfield(app.info,'RescaleSlope') == 1
               slope = app.info.RescaleSlope;
            else
               slope = 1;
            end
            if isfield(app.info,'RescaleIntercept') == 1
               intercept = app.info.RescaleIntercept;
            else
               intercept = 0;
            end
            app.img = dicomread(file);
            app.img = double(app.img);
            app.img = app.img.*slope + intercept;            
            app.cmaps = gray;
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar;
            app.labelStatus.Text = 'Loaded image is resetted.';
            app.labelStatus.Visible = 1;

        end

        % Button pushed function: pushbuttonFlipH
        function pushbuttonFlipH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.figure1.Pointer = 'watch';
            app.img = fliplr(app.img);
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar
            app.figure1.Pointer = 'arrow';
            app.labelStatus.Text = 'Image is flipped vertically.';
            app.labelStatus.Visible = 1;
        end

        % Button pushed function: pushbuttonFlipV
        function pushbuttonFlipV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.figure1.Pointer = 'watch';
            app.img = flipud(app.img);
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar;
            app.figure1.Pointer = 'arrow';
            app.labelStatus.Text = 'Image is flipped horizontally.';
            app.labelStatus.Visible = 1;

        end

        % Button pushed function: pushbuttonRotateC
        function pusshbuttonRotateCW_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.figure1.Pointer = 'watch';
            app.img = imrotate(app.img, 90);
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar
            app.figure1.Pointer = 'arrow';
            app.labelStatus.Text = 'Image is rotated for 90 degrees clockwise.';
            app.labelStatus.Visible = 1;

        end

        % Button pushed function: pushbuttonRotateCC
        function pushbuttonRotateCCW_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.figure1.Pointer = 'watch';
            app.img = imrotate(app.img, -90);
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar
            app.figure1.Pointer = 'arrow';
            app.labelStatus.Text = 'Image is rotated for 90 degrees counter clockwise.';
            app.labelStatus.Visible = 1;

        end

        % Callback function
        function pushButtonSetTresholdCallback(app, event)
            
        end

        % Value changed function: sliderTreshold
        function sliderTresholdValueChangedCallBack(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "sliderTreshold" ) == false            
              app.eventSource = "sliderTreshold";
              value = app.sliderTreshold.Value;
              app.imgTreshold = double(value);
              app.editTreshold.Value = num2str( value );
              app.eventSource = "";
            end 
        end

        % Callback function: editTreshold, editTreshold
        function editTreshold_ValueChanged_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "editTreshold" ) == false            
              app.eventSource = "editTreshold";
              value = str2double( app.editTreshold.Value );
              app.imgTreshold = value;
              app.sliderTreshold.Value = value;
              app.eventSource = "";
            end           
        end

        % Button pushed function: pushbuttonAdjustContrastL
        function pushbuttonAdjustContrastLButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.img = imadjust(app.img, [app.contrastLevelL app.contrastLevelH]);
            app.cmaps =  gray;
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar;
            app.labelStatus.Text = 'Contrast adjusment is applied to image.';
            app.labelStatus.Visible = 1;

        end

        % Value changed function: editContrastLevelLI
        function editContrastLevelLIValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "editContrastLevelLI" ) == false            
              app.eventSource = "editContrastLevelLI";
              value = str2double(app.editContrastLevelLI.Value);
              if value >= app.contrastLevelH
                  app.labelStatus.Text = "Contrast low-in must be smaller than high-in";
                  app.labelStatus.Visible = true;
                  return;
              end
              app.contrastLevelL = value;
              app.sliderContrastLevelLI.Value = value;
              app.eventSource = "";
            end           
            
        end

        % Value changed function: sliderContrastLevelLI
        function sliderContrastLevelLIValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "sliderContrastLevelLI" ) == false            
              app.eventSource = "sliderContrastLevelLI";
              value = app.sliderContrastLevelLI.Value;
              if value >= app.contrastLevelH
                  app.labelStatus.Text = "Contrast low-in must be smaller than high-in";
                  app.labelStatus.Visible = true;
                  return;
              end
              app.contrastLevelL = value;
              app.editContrastLevelLI.Value = num2str( value );
              app.eventSource = "";
            end 
            
        end

        % Button pushed function: pushbuttonEquiliaze
        function pushbuttonEquiliazeButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.img = histeq(app.img);
            app.cmaps = gray;
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar;
            app.labelStatus.Text = 'Histogram equilization is applied to image.';
            app.labelStatus.Visible = 1;
           
        end

        % Button pushed function: pushbuttonAdjustCLAHE
        function pushbuttonAdjustCLAHEButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.img = adapthisteq(app.img);
            app.cmaps = gray;
            imshow(app.img); caxis('auto'); colormap(app.cmaps); colorbar;
            app.labelStatus.Text = 'Adaptive histogram equilization is applied to image.';
            app.labelStatus.Visible = 1;
            
        end

        % Value changed function: sliderContrastLevelHI
        function sliderContrastLevelHIValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "sliderContrastLevelHI" ) == false            
              app.eventSource = "sliderContrastLevelHI";
              value = app.sliderContrastLevelHI.Value;
              if value <= app.contrastLevelL
                  app.labelStatus.Text = "Contrast high-in must be bigger than low-in";
                  app.labelStatus.Visible = true;
                  return;
              end
              app.contrastLevelH = value;
              app.editContrastLevelHI.Value = num2str( value );
              app.eventSource = "";
            end 
            
        end

        % Value changed function: editContrastLevelHI
        function editContrastLevelHIValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if strcmp(app.eventSource, "editContrastLevelHI" ) == false            
              app.eventSource = "editContrastLevelHI";
              value = str2double(app.editContrastLevelHI.Value);
              if value <= app.contrastLevelL
                  app.labelStatus.Text = "Contrast high-in must be bigger than low-in";
                  app.labelStatus.Visible = true;
                  return;
              end
              app.contrastLevelH = value;
              app.sliderContrastLevelHI.Value = value;
              app.eventSource = "";
            end           

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DiCOM Image App';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Visible = 'off';
            app.axes1.Tag = 'axes1';
            app.pushbuttonLoad = uibutton(app.figure1, 'push');
            app.pushbuttonLoad.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLoad_Callback, true);
            app.pushbuttonLoad.Tag = 'pushbuttonLoad';
            app.pushbuttonLoad.Text = 'Load DICOM image';
            app.pushbuttonSegment = uibutton(app.figure1, 'push');
            app.pushbuttonSegment.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSegment_Callback, true);
            app.pushbuttonSegment.Tag = 'pushbuttonSegment';
            app.pushbuttonSegment.Text = 'Segment';
            app.pushbuttonReset = uibutton(app.figure1, 'push');
            app.pushbuttonReset.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonReset_Callback, true);
            app.pushbuttonReset.Tag = 'pushbuttonReset';
            app.pushbuttonReset.Text = 'Reset';
            app.pushbuttonFlipH = uibutton(app.figure1, 'push');
            app.pushbuttonFlipH.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlipH_Callback, true);
            app.pushbuttonFlipH.Tag = 'pushbuttonFlipH';
            app.pushbuttonFlipH.Icon = 'flipH.png';
            app.pushbuttonFlipH.Tooltip = {'Flip vertically'};
            app.pushbuttonFlipH.Text = {''; ''};
            app.pushbuttonFlipV = uibutton(app.figure1, 'push');
            app.pushbuttonFlipV.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFlipV_Callback, true);
            app.pushbuttonFlipV.Tag = 'pushbuttonFlipV';
            app.pushbuttonFlipV.Icon = 'flipV.png';
            app.pushbuttonFlipV.Tooltip = {'Flip horizontally'};
            app.pushbuttonFlipV.Text = '';
            app.pushbuttonRotateC = uibutton(app.figure1, 'push');
            app.pushbuttonRotateC.ButtonPushedFcn = createCallbackFcn(app, @pusshbuttonRotateCW_callback, true);
            app.pushbuttonRotateC.Tag = 'pushbuttonRotateC';
            app.pushbuttonRotateC.Icon = 'rotateC.png';
            app.pushbuttonRotateC.Tooltip = {'Rotate 90 clockwise'};
            app.pushbuttonRotateC.Text = '';
            app.pushbuttonRotateCC = uibutton(app.figure1, 'push');
            app.pushbuttonRotateCC.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRotateCCW_callback, true);
            app.pushbuttonRotateCC.Tag = 'pushbuttonRotateCC';
            app.pushbuttonRotateCC.Icon = 'rotateCC.png';
            app.pushbuttonRotateCC.Tooltip = {'Rotate 90 counter clockwise'};
            app.pushbuttonRotateCC.Text = '';
            app.labelStatus = uilabel(app.figure1);
            app.labelStatus.Text = '';
            app.sliderTreshold = uislider(app.figure1);
            app.sliderTreshold.Limits = [0 1];
            app.sliderTreshold.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.sliderTreshold.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.sliderTreshold.ValueChangedFcn = createCallbackFcn(app, @sliderTresholdValueChangedCallBack, true);
            app.sliderTreshold.Tag = 'sliderTreshold';
            app.sliderTreshold.Value = 0.6;
            app.editTreshold = uieditfield(app.figure1, 'text');
            app.editTreshold.ValueChangedFcn = createCallbackFcn(app, @editTreshold_ValueChanged_Callback, true);
            app.editTreshold.ValueChangingFcn = createCallbackFcn(app, @editTreshold_ValueChanged_Callback, true);
            app.editTreshold.Tag = 'editTreshold';
            app.editTreshold.Value = '0.6';
            app.pushbuttonAdjustContrastL = uibutton(app.figure1, 'push');
            app.pushbuttonAdjustContrastL.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAdjustContrastLButtonPushed, true);
            app.pushbuttonAdjustContrastL.Tag = 'pushbuttonRotateCC';
            app.pushbuttonAdjustContrastL.Tooltip = {'Adjust contrast'};
            app.pushbuttonAdjustContrastL.Text = {'Adjust contrast'; 'with levels'};
            app.labelTreshold = uilabel(app.figure1);
            app.labelTreshold.Text = 'Treshold';
            app.sliderContrastLevelLI = uislider(app.figure1);
            app.sliderContrastLevelLI.Limits = [0 1];
            app.sliderContrastLevelLI.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.sliderContrastLevelLI.MajorTickLabels = {'0', '0.10', '0.20', '0.30', '0.40', '0.50', '0.60', '0.70', '0.80', '0.9', '1'};
            app.sliderContrastLevelLI.ValueChangedFcn = createCallbackFcn(app, @sliderContrastLevelLIValueChanged, true);
            app.sliderContrastLevelLI.Tag = 'sliderContrastLevels';
            app.sliderContrastLevelLI.Value = 0.2;
            app.labelContrastLevelLI = uilabel(app.figure1);
            app.labelContrastLevelLI.Text = {'Contrast'; 'level low-in'};
            app.editContrastLevelLI = uieditfield(app.figure1, 'text');
            app.editContrastLevelLI.ValueChangedFcn = createCallbackFcn(app, @editContrastLevelLIValueChanged, true);
            app.editContrastLevelLI.Tag = 'editTreshold';
            app.editContrastLevelLI.Value = '0.2';
            app.pushbuttonEquiliaze = uibutton(app.figure1, 'push');
            app.pushbuttonEquiliaze.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEquiliazeButtonPushed, true);
            app.pushbuttonEquiliaze.Tag = 'pushbuttonRotateCC';
            app.pushbuttonEquiliaze.Tooltip = {'Histogram equalization'};
            app.pushbuttonEquiliaze.Text = {'Histogram '; 'equalization'};
            app.sliderContrastLevelHI = uislider(app.figure1);
            app.sliderContrastLevelHI.Limits = [0 1];
            app.sliderContrastLevelHI.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.sliderContrastLevelHI.MajorTickLabels = {'0', '0.10', '0.20', '0.30', '0.40', '0.50', '0.60', '0.70', '0.80', '0.9', '1'};
            app.sliderContrastLevelHI.ValueChangedFcn = createCallbackFcn(app, @sliderContrastLevelHIValueChanged, true);
            app.sliderContrastLevelHI.Tag = 'sliderContrastLevels';
            app.sliderContrastLevelHI.Value = 0.8;
            app.labelContrastLevelHI = uilabel(app.figure1);
            app.labelContrastLevelHI.Text = {'Contrast'; 'level high-in'};
            app.editContrastLevelHI = uieditfield(app.figure1, 'text');
            app.editContrastLevelHI.ValueChangedFcn = createCallbackFcn(app, @editContrastLevelHIValueChanged, true);
            app.editContrastLevelHI.Tag = 'editTreshold';
            app.editContrastLevelHI.Value = '0.8';
            app.pushbuttonAdjustCLAHE = uibutton(app.figure1, 'push');
            app.pushbuttonAdjustCLAHE.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAdjustCLAHEButtonPushed, true);
            app.pushbuttonAdjustCLAHE.Tag = 'pushbuttonRotateCC';
            app.pushbuttonAdjustCLAHE.Tooltip = {'Adjust CLAHE'};
            app.pushbuttonAdjustCLAHE.Text = 'Adjust CLAHE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_03_GUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)project_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end